classdef app_cp10_act2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Chapter10Activity2UIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        rpDropDownLabel             matlab.ui.control.Label
        rpDropDown                  matlab.ui.control.DropDown
        GzKnobLabel                 matlab.ui.control.Label
        GzKnob                      matlab.ui.control.DiscreteKnob
        YScaleKnobLabel             matlab.ui.control.Label
        YScaleKnob                  matlab.ui.control.DiscreteKnob
        Button                      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        muSliderLabel               matlab.ui.control.Label
        muSlider                    matlab.ui.control.Slider
        NSliderLabel                matlab.ui.control.Label
        NSlider                     matlab.ui.control.Slider
        k1SliderLabel               matlab.ui.control.Label
        k1Slider                    matlab.ui.control.Slider
        aN1NLabel                   matlab.ui.control.Label
        ParametersTextAreaLabel     matlab.ui.control.Label
        ParametersTextArea          matlab.ui.control.TextArea
        gammaSliderLabel            matlab.ui.control.Label
        gammaSlider                 matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        YOffsetKnobLabel            matlab.ui.control.Label
        YOffsetKnob                 matlab.ui.control.Knob
    end


    properties (Access = private)
        tipo_rp = 1; % Tipo de señal recibida
        tipo_m2 = 1; % Tipo del mensaje 2
        Gz = 1; % Tipo de filtro
        muv = 0;  % Constante de integracion de fase
        gammav = 0;  % Desviacion de frecuencia en tanto por uno de portadora
        N = 1;  % Para el coeficiente a del filtro
        k1v = 0;  % Para el coeficiente k1 del filtro
        scale_4 = 1; % Limite vertical de la cuarta grafica
        offset_4 = 0; % Desplazamiento vertical de la cuarta grafica
        fsv = 16; %Frec. muestreo en MHz
        phiv = 0.8; % Phase offset en rad.
        fcv = 1; %Frec. portadora en MHz
    end

    methods (Access = private)
    
        function UpdatePlots(app)
            sel_rp=app.tipo_rp;
            tipo2=app.tipo_m2;
            selGz=app.Gz;
            
            T=2*1e-2;
            
            fc=app.fcv*1e6;
            fs=app.fsv*1e6;
            phoff=app.phiv;
            
            
            time=100e-6;
            deltaw = 2*pi*1e4;
            
            
            Ts=1/fs; t=Ts:Ts:time;        % time vector
            
            wc=2*pi*fc;
            
            switch sel_rp
                case 1
                    rp=cos((1+app.gammav)*wc.*t+phoff);
                case 2
                    Tm=25e-6;
                    phim=2*pi/Tm*t;
                    wm=sin(phim)+sin(3*phim)/3+sin(5*phim)/5+sin(7*phim)/7;
                    wit=cumsum(wm)/fs;
                    rp=cos(wc*t+deltaw*wit);
                case 3
                    Tm=25e-6;
                    phim=2*pi/Tm*t;
                    wm=square(phim);
                    wit=cumsum(wm)/fs;
                    rp=cos(wc*t+deltaw*wit);
            end
            
            mu=app.muv;
            Na=app.N;
            k1=app.k1v;
                    
            switch selGz
                case 1
                    %mu=-0.04;
                    a=[1, 0];
                    b=1;
                case 2
                    %Na=2;
                    %mu=-0.02*0;
                    a=[1, -(Na-1)/Na];
                    b=1;
                case 3
                    %Na=3;
                    %mu=-0.02*0;
                    a=[1, -(Na-1)/Na];
                    b=1/Na;
                case 4
                    %k1=0.98;
                    k2=1-k1;
                    %mu=-0.02;                             % algorithm stepsize
                    a=[1 -1];                            % autoregressive coefficients
                    b=[k1+k2, -k1];                     % moving average coefficients
            end
            
            
            lena=length(a)-1; lenb=length(b);
            xvec=zeros(lena,1); evec=zeros(lenb,1);     % initial states in filter
            f0=fc;                                      % assumed freq. at receiver
            xv=zeros(1,length(t));                      % initialize vector for estimates
            ev=zeros(1,length(t));                      % initialize vector for estimates
            theta=zeros(1,length(t)); theta(1)=0;       % initialize vector for estimates
            for k=1:length(t)-1                         % e contains past fl+1 inputs
              e=rp(k)*sin(2*pi*f0*t(k)+theta(k));       % input to filter
              evec=[e;evec(1:lenb-1)];                  % past values of inputs
              x=-a(2:lena+1)*xvec+b*evec;               % output of filter
              ev(k)=e;
              xv(k)=x;
              xvec=[x;xvec(1:lena-1,1)];                % past values of outputs
              theta(k+1)=theta(k)+mu*x;                 % algorithm update
            end
            
            b2=fir1(100,16*(deltaw/2/pi)/fs*2);
            dfm=filter(b2,1,xv);
            tu=t*1000;

            
            vA=[tu(1),tu(end),-1.5,1.5];
            plot(app.UIAxes,tu,[sin(2*pi*f0*t+theta);rp]'); axis(app.UIAxes,vA);
            plot(app.UIAxes_2,tu,ev); axis(app.UIAxes_2,vA);
            plot(app.UIAxes_3,tu,xv); axis(app.UIAxes_3,vA);
            plot(app.UIAxes_4,tu,theta); axis(app.UIAxes_4,vA);
            set(app.UIAxes_4,'Ylim',app.scale_4*vA(3:4)+app.offset_4);
            
        end
                
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %axis(app.UIAxes, [0,0.01,-1.2,1.2])
            %app.tipo_m1=1; app.tipo_m2=1;
            %app.phase_error=0;
            parametros={['fc = ',num2str(app.fcv), ' MHz'],['fs = ',num2str(app.fsv), ' MHz'],['phi = ',num2str(app.phiv), ' rad']};
            app.ParametersTextArea.Value=parametros;            
            UpdatePlots(app);
        end

        % Value changed function: rpDropDown
        function rpDropDownValueChanged(app, event)
            value = app.rpDropDown.Value;
            app.tipo_rp=str2num(value);                
            UpdatePlots(app);
        end

        % Callback function
        function m2DropDownValueChanged(app, event)
            value = app.m2DropDown.Value;
            app.tipo_m2=str2num(value);                
            UpdatePlots(app);            
        end

        % Value changed function: NSlider
        function NSliderValueChanged(app, event)
            value = app.NSlider.Value;
            app.N=value;
            UpdatePlots(app);            
        end

        % Value changed function: GzKnob
        function GzKnobValueChanged(app, event)
            value = app.GzKnob.Value;
            app.Gz=str2num(value);
            UpdatePlots(app);                        
        end

        % Value changed function: muSlider
        function muSliderValueChanged(app, event)
            value = app.muSlider.Value;
            app.muv=value;
            UpdatePlots(app);            
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged(app, event)
            value = app.gammaSlider.Value;
            app.gammav=value/100;
            UpdatePlots(app);                        
        end

        % Value changed function: YScaleKnob
        function YScaleKnobValueChanged(app, event)
            value = app.YScaleKnob.Value;
            app.scale_4=str2num(value);
            UpdatePlots(app);                                    
        end

        % Value changed function: k1Slider
        function k1SliderValueChanged(app, event)
            value = app.k1Slider.Value;
            app.k1v=value;
            UpdatePlots(app);                                    
            
        end

        % Value changed function: YOffsetKnob
        function YOffsetKnobValueChanged(app, event)
            value = app.YOffsetKnob.Value;
            app.offset_4=value;
            UpdatePlots(app);                                                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Chapter10Activity2UIFigure = uifigure('Visible', 'off');
            app.Chapter10Activity2UIFigure.Name = 'Chapter 10 Activity 2';
            app.UIAxes = uiaxes(app.Chapter10Activity2UIFigure);
            title(app.UIAxes, 'r_p, s')
            app.UIAxes_2 = uiaxes(app.Chapter10Activity2UIFigure);
            title(app.UIAxes_2, 'e(k)')
            app.UIAxes_3 = uiaxes(app.Chapter10Activity2UIFigure);
            title(app.UIAxes_3, 'x(k)')
            app.UIAxes_4 = uiaxes(app.Chapter10Activity2UIFigure);
            title(app.UIAxes_4, '\theta(k)')
            app.rpDropDownLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.rpDropDownLabel.Text = 'rp';
            app.rpDropDown = uidropdown(app.Chapter10Activity2UIFigure);
            app.rpDropDown.Items = {'Sine', '7 harmonics', 'Square wave'};
            app.rpDropDown.ItemsData = {'1', '2', '3', '4'};
            app.rpDropDown.ValueChangedFcn = createCallbackFcn(app, @rpDropDownValueChanged, true);
            app.rpDropDown.Value = '1';
            app.GzKnobLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.GzKnobLabel.Text = 'G(z)';
            app.GzKnob = uiknob(app.Chapter10Activity2UIFigure, 'discrete');
            app.GzKnob.Items = {'1', 'z/(z-a)', '(1-a) z/(z-a)', 'k1+(1-k1) z/(z-1)'};
            app.GzKnob.ItemsData = {'1', '2', '3', '4'};
            app.GzKnob.ValueChangedFcn = createCallbackFcn(app, @GzKnobValueChanged, true);
            app.GzKnob.Value = '1';
            app.YScaleKnobLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.YScaleKnobLabel.Text = 'Y-Scale';
            app.YScaleKnob = uiknob(app.Chapter10Activity2UIFigure, 'discrete');
            app.YScaleKnob.Items = {'10', '2', '1', '0.1', '0.01', '0.001'};
            app.YScaleKnob.ItemsData = {'10', '2', '1', '0.1', '0.01', '0.001'};
            app.YScaleKnob.ValueChangedFcn = createCallbackFcn(app, @YScaleKnobValueChanged, true);
            app.YScaleKnob.Value = '1';
            app.Button = uibutton(app.Chapter10Activity2UIFigure, 'push');
            app.Button.Icon = 'block_gen_pll.png';
            app.Button.Text = '';
            app.Panel = uipanel(app.Chapter10Activity2UIFigure);
            app.muSliderLabel = uilabel(app.Panel);
            app.muSliderLabel.Text = 'mu';
            app.muSlider = uislider(app.Panel);
            app.muSlider.Limits = [-0.04 0.04];
            app.muSlider.ValueChangedFcn = createCallbackFcn(app, @muSliderValueChanged, true);
            app.NSliderLabel = uilabel(app.Panel);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.Panel);
            app.NSlider.Limits = [1 10];
            app.NSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.NSlider.ValueChangedFcn = createCallbackFcn(app, @NSliderValueChanged, true);
            app.NSlider.MinorTicks = [];
            app.NSlider.Value = 1;
            app.k1SliderLabel = uilabel(app.Panel);
            app.k1SliderLabel.Text = 'k1';
            app.k1Slider = uislider(app.Panel);
            app.k1Slider.Limits = [0 1];
            app.k1Slider.ValueChangedFcn = createCallbackFcn(app, @k1SliderValueChanged, true);
            app.aN1NLabel = uilabel(app.Panel);
            app.aN1NLabel.Text = 'a=(N-1)/N';
            app.ParametersTextAreaLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.ParametersTextAreaLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.Chapter10Activity2UIFigure);
            app.ParametersTextArea.Editable = 'off';
            app.gammaSliderLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.Chapter10Activity2UIFigure);
            app.gammaSlider.Limits = [0 10];
            app.gammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged, true);
            app.Label = uilabel(app.Chapter10Activity2UIFigure);
            app.Label.Text = '(%)';
            app.YOffsetKnobLabel = uilabel(app.Chapter10Activity2UIFigure);
            app.YOffsetKnobLabel.Text = 'Y-Offset';
            app.YOffsetKnob = uiknob(app.Chapter10Activity2UIFigure, 'continuous');
            app.YOffsetKnob.Limits = [-3 3];
            app.YOffsetKnob.ValueChangedFcn = createCallbackFcn(app, @YOffsetKnobValueChanged, true);
            % Show the figure after all components are created
            app.Chapter10Activity2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_cp10_act2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Chapter10Activity2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Chapter10Activity2UIFigure)
        end
    end
end