classdef AM_transceiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCSEAMTransceiverUIFigure  matlab.ui.Figure
        RadioSwitchLabel           matlab.ui.control.Label
        RadioSwitch                matlab.ui.control.Switch
        PTTButton                  matlab.ui.control.Button
        ChannelKnobLabel           matlab.ui.control.Label
        ChannelKnob                matlab.ui.control.DiscreteKnob
    end


    properties (Access = public)
        state = 1;  % 1: Rx, -1: Tx, 0: stop
        chan = 1; % Channel number {1,2}
    end

    methods (Access = private)
    
        function reset_radio(app)
            app.state=1;
            app.RadioSwitch.Value = 'On';
            app.PTTButton.BackgroundColor = 'g';
            AM_transceiver_proc;            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AM_transceiver_proc;
        end

        % Button pushed function: PTTButton
        function PTTButtonPushed(app, event)
            app.state = -app.state;
            if app.state == -1
                app.PTTButton.BackgroundColor = 'r';
            else
                app.PTTButton.BackgroundColor = 'g';
            end
        end

        % Value changed function: RadioSwitch
        function RadioSwitchValueChanged(app, event)
            value = app.RadioSwitch.Value;
            if strcmp(value,'Off')
                app.state=0;
            elseif strcmp(value,'On')
                reset_radio(app);                
            end
        end

        % Value changed function: ChannelKnob
        function ChannelKnobValueChanged(app, event)
            value = app.ChannelKnob.Value;
            app.chan = str2num(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCSEAMTransceiverUIFigure = uifigure;
            app.DCSEAMTransceiverUIFigure.Name = 'DCSE AM Transceiver';
            app.RadioSwitchLabel = uilabel(app.DCSEAMTransceiverUIFigure);
            app.RadioSwitchLabel.Text = 'Radio';
            app.RadioSwitch = uiswitch(app.DCSEAMTransceiverUIFigure, 'slider');
            app.RadioSwitch.ValueChangedFcn = createCallbackFcn(app, @RadioSwitchValueChanged, true);
            app.RadioSwitch.Value = 'On';
            app.PTTButton = uibutton(app.DCSEAMTransceiverUIFigure, 'push');
            app.PTTButton.ButtonPushedFcn = createCallbackFcn(app, @PTTButtonPushed, true);
            app.PTTButton.Text = 'PTT';
            app.ChannelKnobLabel = uilabel(app.DCSEAMTransceiverUIFigure);
            app.ChannelKnobLabel.Text = 'Channel';
            app.ChannelKnob = uiknob(app.DCSEAMTransceiverUIFigure, 'discrete');
            app.ChannelKnob.Items = {'1', '2'};
            app.ChannelKnob.ValueChangedFcn = createCallbackFcn(app, @ChannelKnobValueChanged, true);
            app.ChannelKnob.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AM_transceiver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCSEAMTransceiverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCSEAMTransceiverUIFigure)
        end
    end
end